classdef tarea_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TextArea                   matlab.ui.control.TextArea
        BPMKnob                    matlab.ui.control.Knob
        BPMKnobLabel               matlab.ui.control.Label
        ApneaSpinner               matlab.ui.control.Spinner
        ApneaSpinnerLabel          matlab.ui.control.Label
        PatologyButton             matlab.ui.control.Button
        DurationEditField          matlab.ui.control.NumericEditField
        DurationEditFieldLabel     matlab.ui.control.Label
        ActivationEditField        matlab.ui.control.NumericEditField
        ActivationEditFieldLabel   matlab.ui.control.Label
        FrecuencyEditField         matlab.ui.control.NumericEditField
        FrecuencyEditFieldLabel    matlab.ui.control.Label
        RespirationSpinner         matlab.ui.control.Spinner
        RespirationSpinnerLabel    matlab.ui.control.Label
        SelectsignalDropDown       matlab.ui.control.DropDown
        SelectsignalDropDownLabel  matlab.ui.control.Label
        title                      matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectsignalDropDown
        function SelectsignalDropDownValueChanged(app, event)
            value = app.SelectsignalDropDown.Value;
            fs = app.FrecuencyEditField.Value;
            d = app.DurationEditField.Value;
            A = app.ActivationEditField.Value;
            
            t = 0:1/fs:d;
            amplitud = 1;

            if value == 1
                
            elseif value == 2
                f_base = 10; 
                ruido = 0.03; 
                
                EMG = A * sin(2 * pi * f_base * t) + ruido * randn(1, length(t));
                
                fc = 30; 
                [b, a] = butter(4, fc / (fs / 2), 'low');
                EMG_filtrada = filtfilt(b, a, EMG);
                
                plot(app.UIAxes, EMG_filtrada, 'b', 'LineWidth', 2)
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                app.UIAxes.Title.String = 'EMG';
            
            elseif value == 3
                BPM = 12;
                f_resp = BPM / 60;
                modulacion = 0.3;     
                f_mod = 0.2;          
                
                respiration = A* (amplitud + modulacion * sin(2 * pi * f_mod * t)) .* sin(2 * pi * f_resp * t);
                
                plot(app.UIAxes, respiration, 'b', 'LineWidth', 2)
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                app.UIAxes.Title.String = 'Respiration';

            elseif value == 4                
                matriz = [0.3  , 0.2 , 0.02
                          -0.2 , 0.4  , 0.02
                          1    , 0.5  , 0.01
                          -0.3 , 0.7  , 0.01 
                          0.4  , 0.8 , 0.02  
                          ]; 
                
                onda_p = matriz(1,1)*exp(-((t-matriz(1,2))/matriz(1,3)).^2);
                onda_q = matriz(2,1)*exp(-((t-matriz(2,2))/matriz(2,3)).^2);
                onda_r = matriz(3,1)*exp(-((t-matriz(3,2))/matriz(2,3)).^2);
                onda_s = matriz(4,1)*exp(-((t-matriz(4,2))/matriz(4,3)).^2);
                onda_t = matriz(5,1)*exp(-((t-matriz(5,2))/matriz(5,3)).^2);
                
                ECG = (onda_p + onda_q + onda_r + onda_s + onda_t);
                ECG = repmat(ECG, 1, A);

                plot(app.UIAxes, ECG, 'r', 'LineWidth', 2)
                app.UIAxes.XGrid = 'on';
                app.UIAxes.YGrid = 'on';
                app.UIAxes.Title.String = 'ECG';
            end

        end

        % Value changed function: RespirationSpinner
        function RespirationSpinnerValueChanged(app, event)
            
        end

        % Button pushed function: PatologyButton
        function PatologyButtonPushed2(app, event)
            fs = 100;          
            T = 60;          
            t = 0:1/fs:T;     

            BPM = app.BPMKnob.Value;         
            f_resp = BPM / 60; 
            dur_respiracion = app.RespirationSpinner.Value;  
            dur_apnea = app.ApneaSpinner.Value;    
            
            respiracion = zeros(size(t));
            
            for inicio = 0:(dur_respiracion+dur_apnea):T
                idx = (t >= inicio) & (t < inicio + dur_respiracion);
                respiracion(idx) = sin(2 * pi * f_resp * (t(idx) - inicio));
            end
            
            plot(app.UIAxes,t, respiracion, 'b', 'LineWidth', 2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.title = uilabel(app.UIFigure);
            app.title.Text = {'Synthetic Signal'; ''};
            app.SelectsignalDropDownLabel = uilabel(app.UIFigure);
            app.SelectsignalDropDownLabel.Text = 'Select signal';
            app.SelectsignalDropDown = uidropdown(app.UIFigure);
            app.SelectsignalDropDown.Items = {'Selection', 'EMG', 'Respiration', 'ECG'};
            app.SelectsignalDropDown.ItemsData = [1 2 3 4];
            app.SelectsignalDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectsignalDropDownValueChanged, true);
            app.SelectsignalDropDown.Value = 1;
            app.RespirationSpinnerLabel = uilabel(app.UIFigure);
            app.RespirationSpinnerLabel.Text = 'Respiration';
            app.RespirationSpinner = uispinner(app.UIFigure);
            app.RespirationSpinner.Limits = [0 100];
            app.RespirationSpinner.ValueChangedFcn = createCallbackFcn(app, @RespirationSpinnerValueChanged, true);
            app.FrecuencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuencyEditFieldLabel.Text = 'Frecuency';
            app.FrecuencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.ActivationEditFieldLabel = uilabel(app.UIFigure);
            app.ActivationEditFieldLabel.Text = 'Activation';
            app.ActivationEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.PatologyButton = uibutton(app.UIFigure, 'push');
            app.PatologyButton.ButtonPushedFcn = createCallbackFcn(app, @PatologyButtonPushed2, true);
            app.PatologyButton.Text = 'Patology';
            app.ApneaSpinnerLabel = uilabel(app.UIFigure);
            app.ApneaSpinnerLabel.Text = 'Apnea';
            app.ApneaSpinner = uispinner(app.UIFigure);
            app.ApneaSpinner.Limits = [0 100];
            app.BPMKnobLabel = uilabel(app.UIFigure);
            app.BPMKnobLabel.Text = 'BPM';
            app.BPMKnob = uiknob(app.UIFigure, 'continuous');
            app.BPMKnob.Value = 20;
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Configure each option before requesting signals; Apnea, breathing and BPM settings are for signal with pathology'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tarea_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end